classdef proyectoFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mainTabGroup                    matlab.ui.container.TabGroup
        ecuacionesNoLinealesTab         matlab.ui.container.Tab
        ingresarCriterioButton          matlab.ui.control.Button
        valorCriterioEditField          matlab.ui.control.NumericEditField
        criterioParadaLabel             matlab.ui.control.Label
        criterioDropDown                matlab.ui.control.DropDown
        ingresarEcuacionButton          matlab.ui.control.Button
        SeleccioneelmtodoLabel          matlab.ui.control.Label
        ecuacionEditField               matlab.ui.control.EditField
        SigaelformatodeMatlabEditFieldLabel  matlab.ui.control.Label
        ingreseEcuacionLabel            matlab.ui.control.Label
        ecuacionesNoLinealesTabGroup    matlab.ui.container.TabGroup
        biseccionTab                    matlab.ui.container.Tab
        ingresarValoresInicialesBiseccionButton  matlab.ui.control.Button
        solucionLabel                   matlab.ui.control.Label
        solucionBiseccionTable          matlab.ui.control.Table
        xsBiseccionEditField            matlab.ui.control.NumericEditField
        XsEditFieldLabel                matlab.ui.control.Label
        xiBiseccionEditField            matlab.ui.control.NumericEditField
        XiEditFieldLabel                matlab.ui.control.Label
        valoresInicialesBiseccionLabel  matlab.ui.control.Label
        falsaPosicionTab                matlab.ui.container.Tab
        ingresarValoresInicialesFalsaPosicionButton  matlab.ui.control.Button
        solucionFalsaPosicionTable      matlab.ui.control.Table
        solucionLabel_2                 matlab.ui.control.Label
        xsFalsaPosicionEditField        matlab.ui.control.NumericEditField
        XsEditField_2Label              matlab.ui.control.Label
        valoresInicialesLabel_2         matlab.ui.control.Label
        xiFalsaPosicionEditField        matlab.ui.control.NumericEditField
        XiEditField_2Label              matlab.ui.control.Label
        puntoFijoTab                    matlab.ui.container.Tab
        ingresarGXPuntoFijoButton       matlab.ui.control.Button
        ingresarValorInicialPuntoFijoButton  matlab.ui.control.Button
        convergeLamp                    matlab.ui.control.Lamp
        ConvergeLampLabel               matlab.ui.control.Label
        gXFieldConverge                 matlab.ui.control.EditField
        SigaelformatodeMatlabEditFieldLabel_2  matlab.ui.control.Label
        ingresarGXLabel                 matlab.ui.control.Label
        solucionPuntoFijoTable          matlab.ui.control.Table
        solucionLabel_3                 matlab.ui.control.Label
        valoresInicialesPuntoFijoLabel  matlab.ui.control.Label
        xiPuntoFijoEditField            matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        newtonRaphsonTab                matlab.ui.container.Tab
        ingresarValorInicialNewtonRaphsonButton  matlab.ui.control.Button
        xiNewtonRaphsonEditField        matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        valoresInicialesNewtonRaphsonLabel  matlab.ui.control.Label
        solucionNewtonRaphsonTable      matlab.ui.control.Table
        solucionLabel_4                 matlab.ui.control.Label
        sistemaEcuacionesLinealesTab    matlab.ui.container.Tab
        tablaIndependientes             matlab.ui.control.Table
        ButtonGroup                     matlab.ui.container.ButtonGroup
        tamano5                         matlab.ui.control.RadioButton
        tamano4                         matlab.ui.control.RadioButton
        tamano3                         matlab.ui.control.RadioButton
        tamano2                         matlab.ui.control.RadioButton
        INGRESELASECUACIONESDELSISTEMAORDENADASLabel  matlab.ui.control.Label
        EDITARButton                    matlab.ui.control.Button
        EditartablaLabel                matlab.ui.control.Label
        valorCriterio2                  matlab.ui.control.EditField
        CriterioParadaDropDown          matlab.ui.control.DropDown
        IngreseelcriteriodeparadaLabel  matlab.ui.control.Label
        ResolverButton                  matlab.ui.control.Button
        tablaCoeficientes               matlab.ui.control.Table
        SeleccionelasdimensionesdelsistemaLabel  matlab.ui.control.Label
        sistemasEcuacionesLinealesTabGroup  matlab.ui.container.TabGroup
        jacobiTab                       matlab.ui.container.Tab
        tablaResultadosJacobi           matlab.ui.control.Table
        gaussSeidelTab                  matlab.ui.container.Tab
        tablaResultadosGauss            matlab.ui.control.Table
        InterpolacionesTab              matlab.ui.container.Tab
        IngresarvalorinicialCheckBox    matlab.ui.control.CheckBox
        CalcularInterpolacionButton     matlab.ui.control.Button
        metodoInterpolacionDrop         matlab.ui.control.DropDown
        SeleccioneelmtodoLabel_4        matlab.ui.control.Label
        valorObtenidoField              matlab.ui.control.NumericEditField
        ValorobtenidoLabel              matlab.ui.control.Label
        funcionAjustadaField            matlab.ui.control.EditField
        FUNCINAJUSTADALabel             matlab.ui.control.Label
        valorOpcionalEditField          matlab.ui.control.NumericEditField
        opcionalIngreseunvalordexparaevaluarenlafuncinajustadaLabel  matlab.ui.control.Label
        punto6y                         matlab.ui.control.NumericEditField
        punto5y                         matlab.ui.control.NumericEditField
        punto6x                         matlab.ui.control.NumericEditField
        punto5x                         matlab.ui.control.NumericEditField
        punto3y                         matlab.ui.control.NumericEditField
        punto4x                         matlab.ui.control.NumericEditField
        punto4y                         matlab.ui.control.NumericEditField
        punto3x                         matlab.ui.control.NumericEditField
        punto2y                         matlab.ui.control.NumericEditField
        punto2x                         matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        punto1y                         matlab.ui.control.NumericEditField
        punto1x                         matlab.ui.control.NumericEditField
        IngreselospuntosLabel           matlab.ui.control.Label
        ButtonGroupPuntos               matlab.ui.container.ButtonGroup
        seisPuntos                      matlab.ui.control.RadioButton
        cincoPuntos                     matlab.ui.control.RadioButton
        cuatroPuntos                    matlab.ui.control.RadioButton
        tresPuntos                      matlab.ui.control.RadioButton
        dosPuntos                       matlab.ui.control.RadioButton
        SeleccionelacantidaddepuntosLabel  matlab.ui.control.Label
        DerivadasTab                    matlab.ui.container.Tab
        resultadoVerdaderoDerivadaField  matlab.ui.control.NumericEditField
        RESULTADOVERDADEROLabel         matlab.ui.control.Label
        errorDerivadaField              matlab.ui.control.NumericEditField
        ERRORREALLabel                  matlab.ui.control.Label
        RESULTADOAPROXIMADOLabel        matlab.ui.control.Label
        resultadoAproximadoDerivadaField  matlab.ui.control.NumericEditField
        calcularDerivadaButton          matlab.ui.control.Button
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        IngresevaloresparaxyhLabel      matlab.ui.control.Label
        ordenDerivadaDrop               matlab.ui.control.DropDown
        SeleccioneelordendeladerivadaLabel  matlab.ui.control.Label
        funcionDerivadaEditField        matlab.ui.control.EditField
        SigaelformatodeMatlabEditFieldLabel_3  matlab.ui.control.Label
        IngreselafuncinaderivarLabel    matlab.ui.control.Label
        IntegralesTab                   matlab.ui.container.Tab
        CalcularIntegralButton          matlab.ui.control.Button
        resultadoVerdaderoIntegralField  matlab.ui.control.NumericEditField
        RESULTADOVERDADEROLabel_2       matlab.ui.control.Label
        errorIntegralField              matlab.ui.control.NumericEditField
        ERRORREALLabel_2                matlab.ui.control.Label
        RESULTADOAPROXIMADOLabel_2      matlab.ui.control.Label
        resultadoAproximadoIntegralField  matlab.ui.control.NumericEditField
        metodoIntegralDrop              matlab.ui.control.DropDown
        SeleccioneelmtodoLabel_3        matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        IngresencantidaddeparticionesLabel  matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        IngreseloslmitesdeintegracinLabel  matlab.ui.control.Label
        funcionIntegralEditField        matlab.ui.control.EditField
        SigaelformatodeMatlabEditFieldLabel_4  matlab.ui.control.Label
        IngreselafuncinaintegrarLabel   matlab.ui.control.Label
        EcuacionesDiferencialesTab      matlab.ui.container.Tab
        GRFICALabel                     matlab.ui.control.Label
        iteracionesRungeKutta3EditField  matlab.ui.control.NumericEditField
        iteracionesRungeKutta3Label     matlab.ui.control.Label
        ecuacionDifRungeKutta3EditField  matlab.ui.control.EditField
        RESULTADODELPROCESOLabel        matlab.ui.control.Label
        CalcularRungeKutta3Button       matlab.ui.control.Button
        puntoParadaRungeKutta3EditField  matlab.ui.control.NumericEditField
        yRungeKutta3EditField           matlab.ui.control.NumericEditField
        xRungeKutta3EditField           matlab.ui.control.NumericEditField
        hRungeKutta3EditField           matlab.ui.control.NumericEditField
        k2RungeKutta3EditField          matlab.ui.control.NumericEditField
        k3RungeKutta3EditField          matlab.ui.control.NumericEditField
        k1RungeKutta3EditField          matlab.ui.control.NumericEditField
        resultadoYRungeKutta3EditField  matlab.ui.control.NumericEditField
        xRungeKutta3Label               matlab.ui.control.Label
        ecuacionDifRungeKutta3Label     matlab.ui.control.Label
        k3RungeKutta3Label              matlab.ui.control.Label
        k2RungeKutta3Label              matlab.ui.control.Label
        k1RungeKutta3Label              matlab.ui.control.Label
        resultadoYRungeKutta3Label      matlab.ui.control.Label
        puntoParadaRungeKutta3Label     matlab.ui.control.Label
        yRungeKutta3Label               matlab.ui.control.Label
        hRungeKutta3Label               matlab.ui.control.Label
        InfoRungeKutta3Label            matlab.ui.control.Label
        rungeKutta3UI                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function resolverGaussSeidel(app)
            clc
            criterioSeleccionado = app.CriterioParadaDropDown.Value;
            valorCriterio = str2double(app.valorCriterio2.Value);

            % Limpiamos la tabla de resultados
            app.tablaResultadosGauss.Data = [];

            % matriz de coeficientes ingresada por el usuario
            m = app.tablaCoeficientes.Data;

            % matriz de terminos independientes ingresada por el usuario
            m2 = app.tablaIndependientes.Data;

            % se obtienen la matriz de coeficientes y la matriz de valores
            % independientes
            if app.tamano2.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2});
                    str2double(m{2,1}) str2double(m{2,2})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1})]';

            elseif app.tamano3.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3});
                    str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3});
                    str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1})]';

            elseif app.tamano4.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3}) str2double(m{1,4});
                    str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3}) str2double(m{2,4});
                    str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3}) str2double(m{3,4});
                    str2double(m{4,1}) str2double(m{4,2}) str2double(m{4,3}) str2double(m{4,4})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1}) str2double(m2{4,1})]';

            elseif app.tamano5.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3}) str2double(m{1,4}) str2double(m{1,5});
                    str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3}) str2double(m{2,4}) str2double(m{2,5});
                    str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3}) str2double(m{3,4}) str2double(m{3,5});
                    str2double(m{4,1}) str2double(m{4,2}) str2double(m{4,3}) str2double(m{4,4}) str2double(m{4,5});
                    str2double(m{5,1}) str2double(m{5,2}) str2double(m{5,3}) str2double(m{5,4}) str2double(m{5,5})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1}) str2double(m2{4,1}) str2double(m2{5,1})]';
            end

            numVariables = length(coeficientes);

            for i=1:numVariables
                for j=1:numVariables
                    if i == j
                        D(i,j) = coeficientes(i,j);
                        inD(i,j) = 1/coeficientes(i,j);
                        R(i,j) = 0;
                    else
                        D(i,j) = 0;
                        inD(i,j) = 0;
                        R(i,j) = coeficientes(i,j);
                    end
                end
            end

            % Obtenemos el criterio de parada, ya sea por iteraciones o por
            % error maximo
            if strcmp(criterioSeleccionado,"Iteraciones") == 1
                criterio = 0;
            else
                criterio = 1;
            end

            iteracion = 1;
            error = 100;

            solucion = zeros(1, numVariables);
            errores = zeros(1, numVariables);

            while (criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                solucionAnterior = solucion;

                % LLenamos el vector solucion
                solucion = inD * (indep - R*solucionAnterior');
                solucion = solucion';

                disp(solucion);

                % Llenamos el vector de errores
                for i=1:numVariables
                    if iteracion == 1
                        errores(i) = "N/A";
                        error = 100;
                    else
                        errores(i) = abs((solucion(i) - solucionAnterior(i)) / solucion(i)) * 100;
                        error = max(errores);
                    end
                end

                % LLenamos el vector de valores que se mostrarán en la
                % tabla
                if numVariables == 2
                    default = ["N/A" "N/A" "N/A"];
                elseif numVariables == 3
                    default = ["N/A" "N/A"];
                elseif numVariables == 4
                    default = ["N/A"];
                elseif numVariables == 5
                    default = [];
                end

                arregloValores = [solucion default errores default];

                iteracion = iteracion + 1;

                if isempty(app.tablaResultadosGauss.Data)
                    app.tablaResultadosGauss.Data = arregloValores;
                else
                    app.tablaResultadosGauss.Data = [app.tablaResultadosGauss.Data; arregloValores];
                end
            end
        end

% -----------------------------------------------------------------------------------------------------------------

        function strPolinomio = calcularInterpolacionNewton(app, cantidadPuntos)
            x0 = app.punto1x.Value;
            y0 = app.punto1y.Value;
            x1 = app.punto2x.Value;
            y1 = app.punto2y.Value;

            if cantidadPuntos == 2 
                fx0 = y0;
                fx1 = y1;

                % Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                b0 = fx0;
                b1 = fx0x1;

                b0 = num2str(b0);
                b1 = num2str(b1);
                x0 = num2str(x0);

                strPolinomio = strcat(b0, "+ (", b1, ") * ( x - (", x0 , "))");

            elseif cantidadPuntos == 3
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;

                fx0 = y0;
                fx1 = y1;
                fx2 = y2;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                x0 = num2str(x0);
                x1 = num2str(x1);

                strPolinomio = strcat(b0, " + (", b1, ") * ( x - (", x0 , "))", " + ", b2, "* (x - (", x0 , ")) * ( x - (", x1 , "))");

            elseif cantidadPuntos == 4
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;

                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,"))");

            elseif cantidadPuntos == 5
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;
                x4 = app.punto5x.Value;
                y4 = app.punto5y.Value;

                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));
                fx3x4 = double((fx4 - fx3)/(x4 - x3));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));
                fx2x3x4 = double((fx3x4 - fx2x3)/(x4 - x2));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));
                fx1x2x3x4 = double((fx2x3x4 - fx1x2x3)/(x4 - x1));

                % Cuartas Diferencias
                fx0x1x2x3x4 = double((fx1x2x3x4 - fx0x1x2x3)/(x4 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;
                b4 = fx0x1x2x3x4;

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                b4 = num2str(b4);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);
                x3 = num2str(x3);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - (", x0 , "))", " + (" ...
                    , b2, ") * (x - (", x0 , ")) * (x - (", x1 , ")) + (" ...
                    , b3 , ") * (x - (", x0 , ")) * (x - (", x1, ")) * (x - (", x2,")) + (" ...
                    ,b4,") * (x - (",x0,")) *  (x - (",x1,")) * (x - (",x2,")) * (x - (",x3,"))");
            
            elseif cantidadPuntos == 6
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;
                x4 = app.punto5x.Value;
                y4 = app.punto5y.Value;
                x5 = app.punto6x.Value;
                y5 = app.punto6y.Value;

                fx0 = y0;
                fx1 = y1;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;
                fx5 = y5;

                %Primeras Diferencias
                fx0x1 = double((fx1 - fx0)/(x1 - x0));
                fx1x2 = double((fx2 - fx1)/(x2 - x1));
                fx2x3 = double((fx3 - fx2)/(x3 - x2));
                fx3x4 = double((fx4 - fx3)/(x4 - x3));
                fx4x5 = double((fx5 - fx4)/(x5 - x3));

                %Segundas Diferencias
                fx0x1x2 = double((fx1x2 - fx0x1)/(x2 - x0));
                fx1x2x3 = double((fx2x3 - fx1x2)/(x3 - x1));
                fx2x3x4 = double((fx3x4 - fx2x3)/(x4 - x2));
                fx3x4x5 = double((fx4x5 - fx3x4)/(x5 - x3));

                % Terceras Diferencias
                fx0x1x2x3 = double((fx1x2x3 - fx0x1x2)/(x3 - x0));
                fx1x2x3x4 = double((fx2x3x4 - fx1x2x3)/(x4 - x1));
                fx2x3x4x5 = double((fx3x4x5 - fx2x3x4)/(x5 - x2));

                % Cuartas Diferencias
                fx0x1x2x3x4 = double((fx1x2x3x4 - fx0x1x2x3)/(x4 - x0));
                fx1x2x3x4x5 = double((fx2x3x4x5 - fx1x2x3x4)/(x5 - x1));

                % Quinta Diferencia
                fx0x1x2x3x4x5 = double((fx1x2x3x4x5 - fx0x1x2x3x4)/(x5 - x0));

                b0 = fx0;
                b1 = fx0x1;
                b2 = fx0x1x2;
                b3 = fx0x1x2x3;
                b4 = fx0x1x2x3x4;
                b5 = fx0x1x2x3x4x5;

                b0 = num2str(b0);
                b1 = num2str(b1);
                b2 = num2str(b2);
                b3 = num2str(b3);
                b4 = num2str(b4);
                b5 = num2str(b5);
                x0 = num2str(x0);
                x1 = num2str(x1);
                x2 = num2str(x2);
                x3 = num2str(x3);
                x4 = num2str(x4);

                strPolinomio = strcat(b0, " + (", b1, ") * (x - ", x0 , ")", " + (" ...
                    , b2, ") * (x - ", x0 , ") * (x - ", x1 , ") + (" ...
                    , b3, ") * (x - ", x0 , ") * (x - ", x1, ") * (x - ", x2,") + (" ...
                    , b4, ") * (x - ",x0,") *  (x - ",x1,") * (x - ",x2,") * (x - ",x3,") + (" ...
                    , b5, ") * (x - ",x0,") *  (x - ",x1,") * (x - ",x2,") * (x - ",x3,") * (x - ",x4,")");

            end
        end
        
        function strPolinomio = calcularInterpolacionLagrange(app, cantidadPuntos)
            syms x;

            x0 = app.punto1x.Value;
            y0 = app.punto1y.Value;
            x1 = app.punto2x.Value;
            y1 = app.punto2y.Value;

            fx0 = y0;
            fx1 = y1;

            if cantidadPuntos == 2 
                % Encontrar L0 y L1:
                L0 = (x - x1)/(x0 - x1);
                L1 = (x - x0)/(x1 - x0);

                strPolinomio = string( fx0*(L0) + fx1*(L1) );

            elseif cantidadPuntos == 3
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                fx2 = y2;
                
                % Encontrar L0, L1 y L2
                L0 = ((x - x1)/(x0 - x1)) * ((x - x2)/(x0 - x2));
                L1 = ((x - x0)/(x1 - x0)) * ((x - x2)/(x1 - x2));
                L2 = ((x - x0)/(x2 - x0)) * ((x - x1)/(x2 - x1));

                strPolinomio = string( fx0*(L0) + fx1*(L1) + fx2*(L2) );

            elseif cantidadPuntos == 4
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;
                fx2 = y2;
                fx3 = y3;

                % Encontrar L0, L1. L2 y L3
                L0 = ((x - x1)/(x0 - x1)) * ((x - x2)/(x0 - x2)) * ((x - x3)/(x0 - x3));
                L1 = ((x - x0)/(x1 - x0)) * ((x - x2)/(x1 - x2)) * ((x - x3)/(x1 - x3));
                L2 = ((x - x0)/(x2 - x0)) * ((x - x1)/(x2 - x1)) * ((x - x3)/(x2 - x3));
                L3 = ((x - x0)/(x3 - x0)) * ((x - x1)/(x3 - x1)) * ((x - x2)/(x3 - x2));

                strPolinomio = string( fx0*(L0) + fx1*(L1) + fx2*(L2) + fx3*(L3) );

            elseif cantidadPuntos == 5
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;
                x4 = app.punto5x.Value;
                y4 = app.punto5y.Value;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;

                % Encontrar L0, L1, L2, L3 y L4
                L0 = ((x - x1)/(x0 - x1)) * ((x - x2)/(x0 - x2)) * ((x - x3)/(x0 - x3) * (x - x4)/(x0 - x4));
                L1 = ((x - x0)/(x1 - x0)) * ((x - x2)/(x1 - x2)) * ((x - x3)/(x1 - x3) * (x - x4)/(x1 - x4));
                L2 = ((x - x0)/(x2 - x0)) * ((x - x1)/(x2 - x1)) * ((x - x3)/(x2 - x3) * (x - x4)/(x2 - x4));
                L3 = ((x - x0)/(x3 - x0)) * ((x - x1)/(x3 - x1)) * ((x - x2)/(x3 - x2) * (x - x4)/(x3 - x4));
                L4 = ((x - x0)/(x4 - x0)) * ((x - x1)/(x4 - x1)) * ((x - x2)/(x4 - x2) * (x - x3)/(x4 - x3));

                strPolinomio = string( fx0*(L0) + fx1*(L1) + fx2*(L2) + fx3*(L3) + fx4*(L4) );
            
            elseif cantidadPuntos == 6
                x2 = app.punto3x.Value;
                y2 = app.punto3y.Value;
                x3 = app.punto4x.Value;
                y3 = app.punto4y.Value;
                x4 = app.punto5x.Value;
                y4 = app.punto5y.Value;
                x5 = app.punto6x.Value;
                y5 = app.punto6y.Value;
                fx2 = y2;
                fx3 = y3;
                fx4 = y4;
                fx5 = y5;

                % Encontrar L0, L1, L2, L3, L4 y L5
                L0 = ((x - x1)/(x0 - x1)) * ((x - x2)/(x0 - x2)) * ((x - x3)/(x0 - x3) * (x - x4)/(x0 - x4) * (x - x5)/(x0 - x5));
                L1 = ((x - x0)/(x1 - x0)) * ((x - x2)/(x1 - x2)) * ((x - x3)/(x1 - x3) * (x - x4)/(x1 - x4) * (x - x5)/(x1 - x5));
                L2 = ((x - x0)/(x2 - x0)) * ((x - x1)/(x2 - x1)) * ((x - x3)/(x2 - x3) * (x - x4)/(x2 - x4) * (x - x5)/(x2 - x5));
                L3 = ((x - x0)/(x3 - x0)) * ((x - x1)/(x3 - x1)) * ((x - x2)/(x3 - x2) * (x - x4)/(x3 - x4) * (x - x5)/(x3 - x5));
                L4 = ((x - x0)/(x4 - x0)) * ((x - x1)/(x4 - x1)) * ((x - x2)/(x4 - x2) * (x - x3)/(x4 - x3) * (x - x5)/(x4 - x5));
                L5 = ((x - x0)/(x5 - x0)) * ((x - x1)/(x5 - x1)) * ((x - x2)/(x5 - x2) * (x - x3)/(x5 - x3) * (x - x4)/(x5 - x4));

                strPolinomio = string( fx0*(L0) + fx1*(L1) + fx2*(L2) + fx3*(L3) + fx4*(L4) + fx5*(L5) );
            end
        end
    end
    
% -------------------------------------------------------------------------------------------------------------------

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ingresarEcuacionButton
        function ingresarEcuacionButtonPushed(app, event)
            global ecuacionIngresada;
            ecuacionIngresada = app.ecuacionEditField.Value;

            disp(ecuacionIngresada);
        end

        % Button pushed function: ingresarValoresInicialesBiseccionButton
        function ingresarValoresInicialesBiseccionButtonPushed(app, event)
            clc;
            global criterioSeleccionado;
            global valorCriterio;

            xi = app.xiBiseccionEditField.Value;
            xs = app.xsBiseccionEditField.Value;

            % Limpiamos la tabla de resultados
            app.solucionBiseccionTable.Data = [];

            if xi == xs
                disp('Error: Ingrese límites válidos');
            else
                %declaramos que x va a ser una variable simbolica
                syms x;
                format long;

                %Obtenemos la ecuacion del campo de texto y de una vez la
                %convertimos a variable simbolica con la ecuacion str2sym
                ecuacion = str2sym(app.ecuacionEditField.Value);

                % Obtenemos el criterio de parada, ya sea por iteraciones o por
                % error maximo
                if strcmp(criterioSeleccionado,"Iteraciones") == 1
                    criterio = 0;
                else 
                    criterio = 1;
                end

                iteracion = 1;
                error = 100;

                disp(criterioSeleccionado)

                %Obtenemos el calculo de cada raiz en cada iteracion
                while (criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                    % Aplicamos el metodo de Bisección
                    xr = xi;
                    xra = xr;
                    xr = (xi+xs)/2;
                    fxs = subs(ecuacion, x, xs);
                    fxr = subs(ecuacion, x, xr);
                    fxa = subs(ecuacion, x, xi);
                    
                    if(iteracion == 1)
                        errorDisplay = 'N/A';
                    else
                        error = abs((xr-xra)/xr) * 100;
                        errorDisplay = error;
                    end

                    arregloValores = [string(iteracion), string(xi), string(xr), string(xs), string(double(fxs)), string(double(fxr)), string(double(fxa)), string(errorDisplay)];

                    if((fxr*fxa)>0)
                        xi = xr;
                    end
                    if((fxr*fxa)<0)
                        xs = xr;
                    end

                    iteracion = iteracion+1;

                    disp(['Solución: ' num2str(xr)]);
                    disp(arregloValores);

                    if isempty(app.solucionBiseccionTable.Data)
                        app.solucionBiseccionTable.Data = [arregloValores];
                    else
                        app.solucionBiseccionTable.Data = [app.solucionBiseccionTable.Data; arregloValores];
                    end
                end
            end
        end

        % Button pushed function: ingresarCriterioButton
        function ingresarCriterioButtonPushed(app, event)
            global criterioSeleccionado valorCriterio;
            criterioSeleccionado = app.criterioDropDown.Value;
            valorCriterio = app.valorCriterioEditField.Value;
        end

        % Button pushed function: 
        % ingresarValoresInicialesFalsaPosicionButton
        function ingresarValoresInicialesFalsaPosicionButtonPushed(app, event)
            clc;
            global criterioSeleccionado;
            global valorCriterio;

            xi = app.xiFalsaPosicionEditField.Value;
            xs = app.xsFalsaPosicionEditField.Value;

            % Limpiamos la tabla de resultados
            app.solucionFalsaPosicionTable.Data = [];

            if xi == xs
                disp('Error: Ingrese límites válidos');
            else
                %declaramos que x va a ser una variable simbolica
                syms x;
                format long;

                %Obtenemos la ecuacion del campo de texto y de una vez la
                %convertimos a variable simbolica con la ecuacion str2sym
                ecuacion = str2sym(app.ecuacionEditField.Value);

                % Obtenemos el criterio de parada, ya sea por iteraciones o por
                % error maximo
                if strcmp(criterioSeleccionado,"Iteraciones") == 1
                    criterio = 0;
                else 
                    criterio = 1;
                end

                iteracion = 1;
                error = 100;

                %Obtenemos el calculo de cada raiz en cada iteracion
                while (criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                    % Aplicamos el metodo de Falsa Posición
                    xra = xi;
                    fxs = subs(ecuacion, x, xs);
                    fxa = subs(ecuacion, x, xi);

                    xr = (xs - ((fxs*(xs-xi))/(fxs-fxa)));
                    fxr = subs(ecuacion, x, xr);

                    if(iteracion == 1)
                        errorDisplay = 'N/A';
                    else
                        error = abs((xr-xra)/xr) * 100;
                        error = double(error);
                        errorDisplay = error;
                    end

                    arregloValores = [string(iteracion), string(double(xi)), string(double(xr)), string(double(xs)), string(double(fxs)), string(double(fxr)), string(double(fxa)), string(errorDisplay)];

                    xi = xr;

                    iteracion = iteracion+1;

                    disp(['Solución: ' num2str(double(xr))]);
                    disp(arregloValores);

                    if isempty(app.solucionFalsaPosicionTable.Data)
                        app.solucionFalsaPosicionTable.Data = arregloValores;
                    else
                        app.solucionFalsaPosicionTable.Data = [app.solucionFalsaPosicionTable.Data; arregloValores];
                    end
                end
            end
        end

        % Button pushed function: ingresarValorInicialPuntoFijoButton
        function ingresarValorInicialPuntoFijoButtonPushed(app, event)
            global xa;
            xa = app.xiPuntoFijoEditField.Value;
        end

        % Button pushed function: ingresarGXPuntoFijoButton
        function ingresarGXPuntoFijoButtonPushed(app, event)
            clc;
            global gx;
            global xr;
            global criterioSeleccionado;
            global valorCriterio;

            xr = double(app.xiPuntoFijoEditField.Value);

            %Obtenemos la ecuacion del campo de texto y de una vez la
            %convertimos a variable simbolica con la ecuacion str2sym
            ecuacion = str2sym(app.ecuacionEditField.Value); 
            syms gx(x);
            syms gxx(x);
            gx(x)= str2sym(app.gXFieldConverge.Value);
            gxx= diff(gx);
            convergencia = gxx(xr);

            % Limpiamos la tabla de resultados
            app.solucionPuntoFijoTable.Data = [];

            % Obtenemos el criterio de parada, ya sea por iteraciones o por
            % error maximo
            if strcmp(criterioSeleccionado,"Iteraciones") == 1
                criterio = 0;
            else
                criterio = 1;
            end

            iteracion = 1;
            error = 100;

            disp(criterioSeleccionado)

            while (criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                if convergencia < 1
                    app.convergeLamp.Color = 'g';

                    xr0=xr;

                    %saca el nuevo xr reemplazando xr en x y evaluando el x en la funcion
                    gxr = subs(gx, x, xr0);
                    fx= subs(ecuacion, x, xr);
                    xr = gxr;
                    xr = double(xr);
                else
                    app.convergeLamp.Color = 'r';
                    disp("El g(x) no converge");
                    break;
                end

                if(iteracion == 1)
                    errorDisplay = 'N/A';
                else
                    error = abs((xr-xr0)/xr) * 100;
                    error = double(error);
                    errorDisplay = error;
                end

                arregloValores = [string(iteracion), string(xr), string(double(subs(gx, x, xr))), string(double(fx)), string(errorDisplay)];

                iteracion = iteracion+1;

                disp(['Solución: ' num2str(xr)]); 
                disp(arregloValores);

                if isempty(app.solucionPuntoFijoTable.Data)
                    app.solucionPuntoFijoTable.Data = arregloValores;
                else
                    app.solucionPuntoFijoTable.Data = [app.solucionPuntoFijoTable.Data; arregloValores];
                end
            end
        end

        % Button pushed function: ingresarValorInicialNewtonRaphsonButton
        function ingresarValorInicialNewtonRaphsonButtonPushed(app, event)
            clc;
            xr = app.xiNewtonRaphsonEditField.Value;
            global criterioSeleccionado;
            global valorCriterio;
            syms x;
            %Obtenemos la ecuacion del campo de texto y de una vez la
            %convertimos a variable simbolica con la ecuacion str2sym
            ecuacion = str2sym(app.ecuacionEditField.Value);

            xr= double(xr);

            % Limpiamos la tabla de resultados
            app.solucionNewtonRaphsonTable.Data = [];

            % Obtenemos el criterio de parada, ya sea por iteraciones o por
            % error maximo
            if strcmp(criterioSeleccionado,"Iteraciones") == 1
                criterio = 0;
            else
                criterio = 1;
            end

            iteracion = 1;
            error = 100;

            disp(criterioSeleccionado)

            while(criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                xr0=xr;

                fxr = subs(ecuacion, x, xr); 
                derivadaFx = diff(ecuacion);
                derivadaFxr = double(subs(derivadaFx, x, xr));
                xr = double( xr0 - (fxr/derivadaFxr));

                if(iteracion == 1)
                    errorDisplay = 'N/A';
                else
                    error = abs((xr-xr0)/xr) * 100;
                    errorDisplay = error;
                end

                arregloValores = [string(iteracion), string(xr0), string(double(fxr)), string(derivadaFxr), string(xr), string(errorDisplay)];

                iteracion = iteracion+1;

                disp(['Solución: ' num2str(xr)]);
                disp(arregloValores);


                if isempty(app.solucionNewtonRaphsonTable.Data)
                    app.solucionNewtonRaphsonTable.Data = [arregloValores];
                else
                    app.solucionNewtonRaphsonTable.Data = [app.solucionNewtonRaphsonTable.Data; arregloValores];
                end
            end
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            clc
            criterioSeleccionado = app.CriterioParadaDropDown.Value;
            valorCriterio = str2double(app.valorCriterio2.Value);

            % Limpiamos la tabla de resultados
            app.tablaResultadosJacobi.Data = [];

            % matriz de coeficientes ingresada por el usuario
            m = app.tablaCoeficientes.Data;

            % matriz de terminos independientes ingresada por el usuario
            m2 = app.tablaIndependientes.Data;

            % se obtienen la matriz de coeficientes y la matriz de valores
            % independientes
            if app.tamano2.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}); 
                                str2double(m{2,1}) str2double(m{2,2})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1})]';

            elseif app.tamano3.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3}); 
                                str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3}); 
                                str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1})]';

            elseif app.tamano4.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3}) str2double(m{1,4}); 
                                str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3}) str2double(m{2,4}); 
                                str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3}) str2double(m{3,4}); 
                                str2double(m{4,1}) str2double(m{4,2}) str2double(m{4,3}) str2double(m{4,4})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1}) str2double(m2{4,1})]';

            elseif app.tamano5.Value == 1
                coeficientes = [str2double(m{1,1}) str2double(m{1,2}) str2double(m{1,3}) str2double(m{1,4}) str2double(m{1,5}); 
                                str2double(m{2,1}) str2double(m{2,2}) str2double(m{2,3}) str2double(m{2,4}) str2double(m{2,5}); 
                                str2double(m{3,1}) str2double(m{3,2}) str2double(m{3,3}) str2double(m{3,4}) str2double(m{3,5}); 
                                str2double(m{4,1}) str2double(m{4,2}) str2double(m{4,3}) str2double(m{4,4}) str2double(m{4,5}); 
                                str2double(m{5,1}) str2double(m{5,2}) str2double(m{5,3}) str2double(m{5,4}) str2double(m{5,5})];

                indep = [str2double(m2{1,1}) str2double(m2{2,1}) str2double(m2{3,1}) str2double(m2{4,1}) str2double(m2{5,1})]';
            end

            numVariables = length(coeficientes);

            for i=1:numVariables
                for j=1:numVariables
                    if i == j
                        D(i,j) = coeficientes(i,j);
                        inD(i,j) = 1/coeficientes(i,j);
                        R(i,j) = 0;
                    else
                        D(i,j) = 0;
                        inD(i,j) = 0;
                        R(i,j) = coeficientes(i,j);
                    end
                end
            end

            % Obtenemos el criterio de parada, ya sea por iteraciones o por
            % error maximo
            if strcmp(criterioSeleccionado,"Iteraciones") == 1
                criterio = 0;
            else
                criterio = 1;
            end

            iteracion = 1;
            error = 100;

            solucion = zeros(1, numVariables);
            errores = zeros(1, numVariables);

            while (criterio == 0 && iteracion <= valorCriterio) || (criterio == 1 && error >= valorCriterio)
                solucionAnterior = solucion;

                disp(["ITERACIÓN: " iteracion])

                % LLenamos el vector solucion
                solucion = inD * (indep - R*solucionAnterior');
                solucion = solucion';

                disp(solucion);
                
                % Llenamos el vector de errores
                for i=1:numVariables
                    if iteracion == 1
                        errores(i) = "N/A";
                        error = 100;
                    else
                        errores(i) = abs((solucion(i) - solucionAnterior(i)) / solucion(i)) * 100;
                        error = max(errores);
                    end
                end
                
                disp(errores);

                % LLenamos el vector de valores que se mostrarán en la
                % tabla
                if numVariables == 2
                    default = ["N/A" "N/A" "N/A"];
                elseif numVariables == 3
                    default = ["N/A" "N/A"];
                elseif numVariables == 4
                    default = ["N/A"];
                elseif numVariables == 5
                    default = [];
                end

                arregloValores = [solucion default errores default];
                disp(arregloValores);

                iteracion = iteracion + 1;

                if isempty(app.tablaResultadosJacobi.Data)
                    app.tablaResultadosJacobi.Data = arregloValores;
                else
                    app.tablaResultadosJacobi.Data = [app.tablaResultadosJacobi.Data; arregloValores];
                end
            end

            resolverGaussSeidel(app);
        end

        % Callback function
        function tablaEcuacionesDisplayDataChanged(app, event)

        end

        % Button pushed function: EDITARButton
        function EDITARButtonPushed(app, event)
            if app.tamano2.Value == 1
                app.tablaCoeficientes.Data = cell(2,2);
                app.tablaIndependientes.Data = cell(2, 1);
            elseif app.tamano3.Value == 1
                app.tablaCoeficientes.Data = cell(3,3);
                app.tablaIndependientes.Data = cell(3, 1);
            elseif app.tamano4.Value == 1
                app.tablaCoeficientes.Data = cell(4,4);
                app.tablaIndependientes.Data = cell(4, 1);
            elseif app.tamano5.Value == 1
                app.tablaCoeficientes.Data = cell(5,5);
                app.tablaIndependientes.Data = cell(5, 1);
            end
        end

        % Callback function: ButtonGroup, ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

         
        end

        % Button pushed function: calcularDerivadaButton
        function calcularDerivadaButtonPushed(app, event)
            clc;
            syms x;

            funcion = str2sym(app.funcionDerivadaEditField.Value);
            ordenDerivada = app.ordenDerivadaDrop.Value;
            xi = app.xEditField.Value;
            h = app.hEditField.Value;
            
            % Calculamos los valores de x 
            xm3 = xi - 3*h;
            xm2 = xi - 2*h;
            xm1 = xi - h;
            xp1 = xi + h;
            xp2 = xi + 2*h;
            xp3 = xi + 3*h;

            % Calculamos las f(x) 
            fxi = subs(funcion, x, xi);
            fxm3 = subs(funcion, x, xm3);
            fxm2 = subs(funcion, x, xm2);
            fxm1 = subs(funcion, x, xm1);
            fxp1 = subs(funcion, x, xp1);
            fxp2 = subs(funcion, x, xp2);
            fxp3 = subs(funcion, x, xp3);

            % Primera derivada
            if strcmp(ordenDerivada, "Primera")
                resultado = double( (-fxp2 + 8*fxp1 -8*fxm1 + fxm2)/12*h );
                derivadaReal = diff(funcion, x, 1);
            
            % Segunda derivada
            elseif strcmp(ordenDerivada, "Segunda")
                resultado = double( (-fxp2 + 16*fxp1 - 30*fxi + 16*fxm1 - fxm2)/12*h^2 );
                derivadaReal = diff(funcion, x, 2);
            
            % Tercera derivada
            elseif strcmp(ordenDerivada, "Tercera")
                resultado = double( (-fxp3 + 8*fxp2 - 13*fxp1 + 13*fxm1 - 8*fxm2 + fxm3)/8*h^3 );
                derivadaReal = diff(funcion, x, 3);

            % Cuarta derivada
            elseif strcmp(ordenDerivada, "Cuarta")
                resultado = double( (-fxp3 + 12*fxp2 + 39*fxp1 + 56*fxi - 39*fxm1 + 12*fxm2 + fxm3)/6*h^4 );
                derivadaReal = diff(funcion, x, 4);
            end

            derivadaRealEvaluada = double( subs(derivadaReal, x, xi) );
            error = double( abs((derivadaRealEvaluada - resultado)/derivadaRealEvaluada) * 100 );

            app.resultadoAproximadoDerivadaField.Value = resultado;
            app.resultadoVerdaderoDerivadaField.Value = derivadaRealEvaluada;
            app.errorDerivadaField.Value = error;
        end

        % Button pushed function: CalcularIntegralButton
        function CalcularIntegralButtonPushed(app, event)
            syms x;
            n = app.nEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            funcion = str2sym(app.funcionIntegralEditField.Value);
            metodo = app.metodoIntegralDrop.Value;

            if(metodo == "Trapecio")
                h = (b - a)/n;
                i = 1;
                %funcion evaluada en x0 y xn
                sum1 = double(subs(funcion, x, a) + subs(funcion, x, b));
                sumatoria = 0;

                %de 1 a n-1 hace la sumatoria de los intermedios
                while i < n
                    xi = a + i * h;
                    sumatoria = double(sumatoria + subs(funcion, x, xi));
                    i = i + 1;
                end
                resultado = double(((b-a)/(2*n))*(sum1 + 2*sumatoria)); 
            
            elseif(metodo == "Simpson 1/3")
                
                h = (b - a)/n;
                i = 1;
                %funcion evaluada en x0 y xn
                sum2 = double(subs(funcion, x, a) + subs(funcion, x, b));
                sumatoriaPar = 0;
                sumatoriaImpar = 0;

                while i < n
                    %calcula el xi y evalua si es par o impar con el modulo
                    xi = a + i * h;
                    if mod(i,2)==0
                        sumatoriaPar = double(sumatoriaPar + subs(funcion, x, xi));
                        i = i + 1;
                    else
                        sumatoriaImpar = double(sumatoriaImpar + subs(funcion, x, xi));
                        i = i + 1;
                    end
                end
                
                %resultado = double((b-a)*(sum2+(4*sumatoriaImpar)+(2*sumatoriaPar))/(3*n));
                resultado = double((h/3)*(sum2+(4*sumatoriaImpar)+(2*sumatoriaPar)));

            elseif(metodo == "Simpson 3/8")
                h = (b - a)/n;
                i = 1;
                xi=a;
                %funcion evaluada en x0 y xn
                f0 = double(subs(funcion,x,a));
                f1 = double(subs(funcion,x,b));
                sumatoriaTres=0;
                sumatoriaOtros=0;

                while i<n
                    xi = a + i * h;
                    if mod(i,3)==0
                        %suma de los multiplos de 3
                        sumatoriaTres = double(sumatoriaTres + subs(funcion, x, xi));
                        i = i + 1;
                    else
                        %sumatoria de 1,2,4,5,7,8...
                        sumatoriaOtros = double(sumatoriaOtros + subs(funcion, x, xi));
                        i = i + 1;
                    end
                    %se actualiza el valor de xi para la siguiente iteración
                end
                resultado = double(((3/8)*(h))*((f0+(3*sumatoriaOtros)+(2*sumatoriaTres)+f1)));
            end
           
            app.resultadoAproximadoIntegralField.Value = resultado;
            
            valorReal = (integral(matlabFunction(funcion), a, b));
            app.resultadoVerdaderoIntegralField.Value = valorReal;

            error = double(abs(((valorReal) - (resultado))/(valorReal))*100);
            app.errorIntegralField.Value = error;
        end

        % Button pushed function: CalcularRungeKutta3Button
        function CalcularRungeKutta3ButtonPushed(app, event)
            datosx = [];
            datosy = [];
            syms x
            syms y

            ecuacion = str2sym(app.ecuacionDifRungeKutta3EditField.Value);
            h = app.hRungeKutta3EditField.Value; 
            a = app.xRungeKutta3EditField.Value;
            datosx = [datosx, a];
            b = app.yRungeKutta3EditField.Value;
            datosy = [datosy, b];
            contador=0;
    
            puntoParada = app.puntoParadaRungeKutta3EditField.Value;

            while a < puntoParada
                k1 = double(subs(ecuacion, {x,y},{a,b}));
                k2 = double(subs(ecuacion, {x,y},{a+((1/2)*h),b+((1/2)*k1*h)}));
                k3 = double(subs(ecuacion, {x,y},{a+h,b-(k1*h)+(2*k2*h)}));
                yi = double(b+((h/6)*((k1)+(4*k2)+(k3))));

                a=a+h;
                b=yi;
                datosx = [datosx, a];
                datosy = [datosy, b];

                contador=contador+1;
            end
            app.resultadoYRungeKutta3EditField.Value = yi;
            app.k1RungeKutta3EditField.Value = k1;
            app.k2RungeKutta3EditField.Value = k2;
            app.k3RungeKutta3EditField.Value = k3;
            app.iteracionesRungeKutta3EditField.Value = contador;
            disp(datosx);
            disp(datosy);   
            disp(contador);
            %hacer lo de la grafica
            plot(app.rungeKutta3UI, datosx, datosy, 'o-');
        end

        % Selection changed function: ButtonGroupPuntos
        function ButtonGroupPuntosSelectionChanged(app, event)
            global cantidadPuntos;
            cantidadPuntos = str2double(app.ButtonGroupPuntos.SelectedObject.Text);

            if cantidadPuntos == 2
                app.punto3x.Enable = false;
                app.punto3y.Enable = false;
                app.punto4x.Enable = false;
                app.punto4y.Enable = false;
                app.punto5x.Enable = false;
                app.punto5y.Enable = false;
                app.punto6x.Enable = false;
                app.punto6y.Enable = false;

            elseif cantidadPuntos == 3
                app.punto3x.Enable = true;
                app.punto3y.Enable = true;
                app.punto4x.Enable = false;
                app.punto4y.Enable = false;
                app.punto5x.Enable = false;
                app.punto5y.Enable = false;
                app.punto6x.Enable = false;
                app.punto6y.Enable = false;
             
            elseif cantidadPuntos == 4
                app.punto3x.Enable = true;
                app.punto3y.Enable = true;
                app.punto4x.Enable = true;
                app.punto4y.Enable = true;
                app.punto5x.Enable = false;
                app.punto5y.Enable = false;
                app.punto6x.Enable = false;
                app.punto6y.Enable = false;

            elseif cantidadPuntos == 5
                app.punto3x.Enable = true;
                app.punto3y.Enable = true;
                app.punto4x.Enable = true;
                app.punto4y.Enable = true;
                app.punto5x.Enable = true;
                app.punto5y.Enable = true;
                app.punto6x.Enable = false;
                app.punto6y.Enable = false;  

             elseif cantidadPuntos == 6
                app.punto3x.Enable = true;
                app.punto3y.Enable = true;
                app.punto4x.Enable = true;
                app.punto4y.Enable = true;
                app.punto5x.Enable = true;
                app.punto5y.Enable = true;
                app.punto6x.Enable = true;
                app.punto6y.Enable = true;
            end
        end

        % Button pushed function: CalcularInterpolacionButton
        function CalcularInterpolacionButtonPushed(app, event)
            global cantidadPuntos;
            
            clc;
            syms x;
            
            valorOpcional = app.valorOpcionalEditField.Value;
            valorIngresado = app.IngresarvalorinicialCheckBox.Value;
           
            metodo = app.metodoInterpolacionDrop.Value;
            
            if strcmp(metodo, "Newton")
                app.funcionAjustadaField.Value = calcularInterpolacionNewton(app, cantidadPuntos);
            else
                app.funcionAjustadaField.Value = calcularInterpolacionLagrange(app, cantidadPuntos);
            end

            if valorIngresado == 1
                evaluacion = subs( str2sym(app.funcionAjustadaField.Value), x, double(valorOpcional) );
                app.valorObtenidoField.Value = double(evaluacion);
            end
        end

        % Value changed function: IngresarvalorinicialCheckBox
        function IngresarvalorinicialCheckBoxValueChanged(app, event)
            value = app.IngresarvalorinicialCheckBox.Value;
            
            if value == 0
                app.valorOpcionalEditField.Enable = false;
                app.valorObtenidoField.Enable = false;
            else
                app.valorOpcionalEditField.Enable = true;
                app.valorObtenidoField.Enable = true;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mainTabGroup = uitabgroup(app.UIFigure);
            app.ecuacionesNoLinealesTab = uitab(app.mainTabGroup);
            app.ecuacionesNoLinealesTab.Title = 'Ecuaciones No Lineales';
            app.ecuacionesNoLinealesTabGroup = uitabgroup(app.ecuacionesNoLinealesTab);
            app.biseccionTab = uitab(app.ecuacionesNoLinealesTabGroup);
            app.biseccionTab.Title = 'Bisección';
            app.valoresInicialesBiseccionLabel = uilabel(app.biseccionTab);
            app.valoresInicialesBiseccionLabel.Text = '4. Ingrese los valores iniciales';
            app.XiEditFieldLabel = uilabel(app.biseccionTab);
            app.XiEditFieldLabel.Text = 'Xi';
            app.xiBiseccionEditField = uieditfield(app.biseccionTab, 'numeric');
            app.XsEditFieldLabel = uilabel(app.biseccionTab);
            app.XsEditFieldLabel.Text = 'Xs';
            app.xsBiseccionEditField = uieditfield(app.biseccionTab, 'numeric');
            app.solucionBiseccionTable = uitable(app.biseccionTab);
            app.solucionBiseccionTable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'F(Xi)'; 'F(Xr)'; 'F(Xs)'; 'Error (a) [%]'};
            app.solucionBiseccionTable.RowName = {};
            app.solucionLabel = uilabel(app.biseccionTab);
            app.solucionLabel.Text = 'SOLUCIÓN';
            app.ingresarValoresInicialesBiseccionButton = uibutton(app.biseccionTab, 'push');
            app.ingresarValoresInicialesBiseccionButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarValoresInicialesBiseccionButtonPushed, true);
            app.ingresarValoresInicialesBiseccionButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarValoresInicialesBiseccionButton.Text = '';
            app.falsaPosicionTab = uitab(app.ecuacionesNoLinealesTabGroup);
            app.falsaPosicionTab.Title = 'Falsa Posición';
            app.XiEditField_2Label = uilabel(app.falsaPosicionTab);
            app.XiEditField_2Label.Text = 'Xi';
            app.xiFalsaPosicionEditField = uieditfield(app.falsaPosicionTab, 'numeric');
            app.valoresInicialesLabel_2 = uilabel(app.falsaPosicionTab);
            app.valoresInicialesLabel_2.Text = '4. Ingrese los valores iniciales';
            app.XsEditField_2Label = uilabel(app.falsaPosicionTab);
            app.XsEditField_2Label.Text = 'Xs';
            app.xsFalsaPosicionEditField = uieditfield(app.falsaPosicionTab, 'numeric');
            app.solucionLabel_2 = uilabel(app.falsaPosicionTab);
            app.solucionLabel_2.Text = 'SOLUCIÓN';
            app.solucionFalsaPosicionTable = uitable(app.falsaPosicionTab);
            app.solucionFalsaPosicionTable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'F(Xi)'; 'F(Xr)'; 'F(Xs)'; 'Error (a) [%]'};
            app.solucionFalsaPosicionTable.RowName = {};
            app.ingresarValoresInicialesFalsaPosicionButton = uibutton(app.falsaPosicionTab, 'push');
            app.ingresarValoresInicialesFalsaPosicionButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarValoresInicialesFalsaPosicionButtonPushed, true);
            app.ingresarValoresInicialesFalsaPosicionButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarValoresInicialesFalsaPosicionButton.Text = '';
            app.puntoFijoTab = uitab(app.ecuacionesNoLinealesTabGroup);
            app.puntoFijoTab.Title = 'Punto Fijo';
            app.XLabel = uilabel(app.puntoFijoTab);
            app.XLabel.Text = 'X';
            app.xiPuntoFijoEditField = uieditfield(app.puntoFijoTab, 'numeric');
            app.valoresInicialesPuntoFijoLabel = uilabel(app.puntoFijoTab);
            app.valoresInicialesPuntoFijoLabel.Text = '4. Ingrese el valor inicial';
            app.solucionLabel_3 = uilabel(app.puntoFijoTab);
            app.solucionLabel_3.Text = 'SOLUCIÓN';
            app.solucionPuntoFijoTable = uitable(app.puntoFijoTab);
            app.solucionPuntoFijoTable.ColumnName = {'Iteración'; 'x'; 'g(x)'; 'f(x)'; 'Error (a) [%]'};
            app.solucionPuntoFijoTable.RowName = {};
            app.ingresarGXLabel = uilabel(app.puntoFijoTab);
            app.ingresarGXLabel.Text = '5. Ingrese g(x)';
            app.SigaelformatodeMatlabEditFieldLabel_2 = uilabel(app.puntoFijoTab);
            app.SigaelformatodeMatlabEditFieldLabel_2.Text = 'Siga el formato de Matlab';
            app.gXFieldConverge = uieditfield(app.puntoFijoTab, 'text');
            app.ConvergeLampLabel = uilabel(app.puntoFijoTab);
            app.ConvergeLampLabel.Text = '¿Converge?';
            app.convergeLamp = uilamp(app.puntoFijoTab);
            app.ingresarValorInicialPuntoFijoButton = uibutton(app.puntoFijoTab, 'push');
            app.ingresarValorInicialPuntoFijoButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarValorInicialPuntoFijoButtonPushed, true);
            app.ingresarValorInicialPuntoFijoButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarValorInicialPuntoFijoButton.Text = '';
            app.ingresarGXPuntoFijoButton = uibutton(app.puntoFijoTab, 'push');
            app.ingresarGXPuntoFijoButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarGXPuntoFijoButtonPushed, true);
            app.ingresarGXPuntoFijoButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarGXPuntoFijoButton.Text = '';
            app.newtonRaphsonTab = uitab(app.ecuacionesNoLinealesTabGroup);
            app.newtonRaphsonTab.Title = 'Newton-Raphson';
            app.solucionLabel_4 = uilabel(app.newtonRaphsonTab);
            app.solucionLabel_4.Text = 'SOLUCIÓN';
            app.solucionNewtonRaphsonTable = uitable(app.newtonRaphsonTab);
            app.solucionNewtonRaphsonTable.ColumnName = {'Iteración'; 'Xi'; 'F(Xi)'; 'F''(Xi)'; 'X_(i+1)'; 'Error (a) [%]'};
            app.solucionNewtonRaphsonTable.RowName = {};
            app.valoresInicialesNewtonRaphsonLabel = uilabel(app.newtonRaphsonTab);
            app.valoresInicialesNewtonRaphsonLabel.Text = '4. Ingrese el valor inicial';
            app.XLabel_2 = uilabel(app.newtonRaphsonTab);
            app.XLabel_2.Text = 'X';
            app.xiNewtonRaphsonEditField = uieditfield(app.newtonRaphsonTab, 'numeric');
            app.ingresarValorInicialNewtonRaphsonButton = uibutton(app.newtonRaphsonTab, 'push');
            app.ingresarValorInicialNewtonRaphsonButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarValorInicialNewtonRaphsonButtonPushed, true);
            app.ingresarValorInicialNewtonRaphsonButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarValorInicialNewtonRaphsonButton.Text = '';
            app.ingreseEcuacionLabel = uilabel(app.ecuacionesNoLinealesTab);
            app.ingreseEcuacionLabel.Text = '1. Ingrese la ecuación en términos de x';
            app.SigaelformatodeMatlabEditFieldLabel = uilabel(app.ecuacionesNoLinealesTab);
            app.SigaelformatodeMatlabEditFieldLabel.Text = 'Siga el formato de Matlab';
            app.ecuacionEditField = uieditfield(app.ecuacionesNoLinealesTab, 'text');
            app.SeleccioneelmtodoLabel = uilabel(app.ecuacionesNoLinealesTab);
            app.SeleccioneelmtodoLabel.Text = '2. Seleccione el método';
            app.ingresarEcuacionButton = uibutton(app.ecuacionesNoLinealesTab, 'push');
            app.ingresarEcuacionButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarEcuacionButtonPushed, true);
            app.ingresarEcuacionButton.Text = 'Ingresar';
            app.criterioDropDown = uidropdown(app.ecuacionesNoLinealesTab);
            app.criterioDropDown.Items = {'Iteraciones', 'Error máx (%)'};
            app.criterioDropDown.Value = 'Error máx (%)';
            app.criterioParadaLabel = uilabel(app.ecuacionesNoLinealesTab);
            app.criterioParadaLabel.Text = '3. Ingrese el criterio de parada';
            app.valorCriterioEditField = uieditfield(app.ecuacionesNoLinealesTab, 'numeric');
            app.ingresarCriterioButton = uibutton(app.ecuacionesNoLinealesTab, 'push');
            app.ingresarCriterioButton.ButtonPushedFcn = createCallbackFcn(app, @ingresarCriterioButtonPushed, true);
            app.ingresarCriterioButton.Icon = fullfile(pathToMLAPP, 'enter.svg');
            app.ingresarCriterioButton.Text = '';
            app.sistemaEcuacionesLinealesTab = uitab(app.mainTabGroup);
            app.sistemaEcuacionesLinealesTab.Title = 'Sistema de Ecuaciones Lineales';
            app.sistemasEcuacionesLinealesTabGroup = uitabgroup(app.sistemaEcuacionesLinealesTab);
            app.jacobiTab = uitab(app.sistemasEcuacionesLinealesTabGroup);
            app.jacobiTab.Title = 'Jacobi';
            app.tablaResultadosJacobi = uitable(app.jacobiTab);
            app.tablaResultadosJacobi.ColumnName = {'X1'; 'X2'; 'X3'; 'X4'; 'X5'; 'Ex1 (%)'; 'Ex2 (%)'; 'Ex3 (%)'; 'Ex4 (%)'; 'Ex5 (%)'};
            app.gaussSeidelTab = uitab(app.sistemasEcuacionesLinealesTabGroup);
            app.gaussSeidelTab.Title = 'Gauss-Seidel';
            app.tablaResultadosGauss = uitable(app.gaussSeidelTab);
            app.tablaResultadosGauss.ColumnName = {'X1'; 'X2'; 'X3'; 'X4'; 'X5'; 'Ex1 (%)'; 'Ex2 (%)'; 'Ex3 (%)'; 'Ex4 (%)'; 'Ex5 (%)'};
            app.SeleccionelasdimensionesdelsistemaLabel = uilabel(app.sistemaEcuacionesLinealesTab);
            app.SeleccionelasdimensionesdelsistemaLabel.Text = '1. Seleccione las dimensiones del sistema';
            app.tablaCoeficientes = uitable(app.sistemaEcuacionesLinealesTab);
            app.tablaCoeficientes.ColumnName = {'X1'; 'X2'; 'X3'; 'X4'; 'X5'};
            app.tablaCoeficientes.ColumnEditable = true;
            app.ResolverButton = uibutton(app.sistemaEcuacionesLinealesTab, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.IngreseelcriteriodeparadaLabel = uilabel(app.sistemaEcuacionesLinealesTab);
            app.IngreseelcriteriodeparadaLabel.Text = '3. Ingrese el criterio de parada';
            app.CriterioParadaDropDown = uidropdown(app.sistemaEcuacionesLinealesTab);
            app.CriterioParadaDropDown.Items = {'Iteraciones', 'Error máx (%)'};
            app.CriterioParadaDropDown.Value = 'Error máx (%)';
            app.valorCriterio2 = uieditfield(app.sistemaEcuacionesLinealesTab, 'text');
            app.EditartablaLabel = uilabel(app.sistemaEcuacionesLinealesTab);
            app.EditartablaLabel.Text = '2. Editar tabla';
            app.EDITARButton = uibutton(app.sistemaEcuacionesLinealesTab, 'push');
            app.EDITARButton.ButtonPushedFcn = createCallbackFcn(app, @EDITARButtonPushed, true);
            app.EDITARButton.Text = 'EDITAR';
            app.INGRESELASECUACIONESDELSISTEMAORDENADASLabel = uilabel(app.sistemaEcuacionesLinealesTab);
            app.INGRESELASECUACIONESDELSISTEMAORDENADASLabel.Text = 'INGRESE LAS ECUACIONES DEL SISTEMA (ORDENADAS)';
            app.ButtonGroup = uibuttongroup(app.sistemaEcuacionesLinealesTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.tamano2 = uiradiobutton(app.ButtonGroup);
            app.tamano2.Text = '2';
            app.tamano2.Value = true;
            app.tamano3 = uiradiobutton(app.ButtonGroup);
            app.tamano3.Text = '3';
            app.tamano4 = uiradiobutton(app.ButtonGroup);
            app.tamano4.Text = '4';
            app.tamano5 = uiradiobutton(app.ButtonGroup);
            app.tamano5.Text = '5';
            app.tablaIndependientes = uitable(app.sistemaEcuacionesLinealesTab);
            app.tablaIndependientes.ColumnName = {'='};
            app.tablaIndependientes.RowName = {};
            app.tablaIndependientes.ColumnEditable = true;
            app.InterpolacionesTab = uitab(app.mainTabGroup);
            app.InterpolacionesTab.Title = 'Interpolaciones';
            app.SeleccionelacantidaddepuntosLabel = uilabel(app.InterpolacionesTab);
            app.SeleccionelacantidaddepuntosLabel.Text = '1. Seleccione la cantidad de puntos';
            app.ButtonGroupPuntos = uibuttongroup(app.InterpolacionesTab);
            app.ButtonGroupPuntos.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupPuntosSelectionChanged, true);
            app.dosPuntos = uiradiobutton(app.ButtonGroupPuntos);
            app.dosPuntos.Text = '2';
            app.dosPuntos.Value = true;
            app.tresPuntos = uiradiobutton(app.ButtonGroupPuntos);
            app.tresPuntos.Text = '3';
            app.cuatroPuntos = uiradiobutton(app.ButtonGroupPuntos);
            app.cuatroPuntos.Text = '4';
            app.cincoPuntos = uiradiobutton(app.ButtonGroupPuntos);
            app.cincoPuntos.Text = '5';
            app.seisPuntos = uiradiobutton(app.ButtonGroupPuntos);
            app.seisPuntos.Text = '6';
            app.IngreselospuntosLabel = uilabel(app.InterpolacionesTab);
            app.IngreselospuntosLabel.Text = '2. Ingrese los puntos';
            app.punto1x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto1y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.XLabel_3 = uilabel(app.InterpolacionesTab);
            app.XLabel_3.Text = 'X';
            app.YLabel = uilabel(app.InterpolacionesTab);
            app.YLabel.Text = 'Y';
            app.punto2x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto2y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto3x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto4y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto4x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto3y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto5x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto6x = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto5y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.punto6y = uieditfield(app.InterpolacionesTab, 'numeric');
            app.opcionalIngreseunvalordexparaevaluarenlafuncinajustadaLabel = uilabel(app.InterpolacionesTab);
            app.opcionalIngreseunvalordexparaevaluarenlafuncinajustadaLabel.Text = '4 (opcional). Ingrese un valor de x para evaluar en la función ajustada ';
            app.valorOpcionalEditField = uieditfield(app.InterpolacionesTab, 'numeric');
            app.valorOpcionalEditField.Enable = 'off';
            app.FUNCINAJUSTADALabel = uilabel(app.InterpolacionesTab);
            app.FUNCINAJUSTADALabel.Text = 'FUNCIÓN AJUSTADA';
            app.funcionAjustadaField = uieditfield(app.InterpolacionesTab, 'text');
            app.funcionAjustadaField.Editable = 'off';
            app.ValorobtenidoLabel = uilabel(app.InterpolacionesTab);
            app.ValorobtenidoLabel.Text = 'Valor obtenido';
            app.valorObtenidoField = uieditfield(app.InterpolacionesTab, 'numeric');
            app.valorObtenidoField.Editable = 'off';
            app.valorObtenidoField.Enable = 'off';
            app.SeleccioneelmtodoLabel_4 = uilabel(app.InterpolacionesTab);
            app.SeleccioneelmtodoLabel_4.Text = '3. Seleccione el método';
            app.metodoInterpolacionDrop = uidropdown(app.InterpolacionesTab);
            app.metodoInterpolacionDrop.Items = {'Newton', 'Lagrange'};
            app.metodoInterpolacionDrop.Value = 'Newton';
            app.CalcularInterpolacionButton = uibutton(app.InterpolacionesTab, 'push');
            app.CalcularInterpolacionButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularInterpolacionButtonPushed, true);
            app.CalcularInterpolacionButton.Text = 'Calcular';
            app.IngresarvalorinicialCheckBox = uicheckbox(app.InterpolacionesTab);
            app.IngresarvalorinicialCheckBox.ValueChangedFcn = createCallbackFcn(app, @IngresarvalorinicialCheckBoxValueChanged, true);
            app.IngresarvalorinicialCheckBox.Text = 'Ingresar valor inicial';
            app.DerivadasTab = uitab(app.mainTabGroup);
            app.DerivadasTab.Title = 'Derivadas';
            app.IngreselafuncinaderivarLabel = uilabel(app.DerivadasTab);
            app.IngreselafuncinaderivarLabel.Text = '1. Ingrese la función a derivar';
            app.SigaelformatodeMatlabEditFieldLabel_3 = uilabel(app.DerivadasTab);
            app.SigaelformatodeMatlabEditFieldLabel_3.Text = 'Siga el formato de Matlab';
            app.funcionDerivadaEditField = uieditfield(app.DerivadasTab, 'text');
            app.SeleccioneelordendeladerivadaLabel = uilabel(app.DerivadasTab);
            app.SeleccioneelordendeladerivadaLabel.Text = '2. Seleccione el orden de la derivada';
            app.ordenDerivadaDrop = uidropdown(app.DerivadasTab);
            app.ordenDerivadaDrop.Items = {'Primera', 'Segunda', 'Tercera', 'Cuarta'};
            app.ordenDerivadaDrop.Value = 'Primera';
            app.IngresevaloresparaxyhLabel = uilabel(app.DerivadasTab);
            app.IngresevaloresparaxyhLabel.Text = '3. Ingrese valores para x y h';
            app.xEditFieldLabel = uilabel(app.DerivadasTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DerivadasTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.DerivadasTab);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.DerivadasTab, 'numeric');
            app.calcularDerivadaButton = uibutton(app.DerivadasTab, 'push');
            app.calcularDerivadaButton.ButtonPushedFcn = createCallbackFcn(app, @calcularDerivadaButtonPushed, true);
            app.calcularDerivadaButton.Text = 'Calcular';
            app.resultadoAproximadoDerivadaField = uieditfield(app.DerivadasTab, 'numeric');
            app.resultadoAproximadoDerivadaField.Editable = 'off';
            app.RESULTADOAPROXIMADOLabel = uilabel(app.DerivadasTab);
            app.RESULTADOAPROXIMADOLabel.Text = 'RESULTADO APROXIMADO';
            app.ERRORREALLabel = uilabel(app.DerivadasTab);
            app.ERRORREALLabel.Text = 'ERROR REAL (%)';
            app.errorDerivadaField = uieditfield(app.DerivadasTab, 'numeric');
            app.errorDerivadaField.Editable = 'off';
            app.RESULTADOVERDADEROLabel = uilabel(app.DerivadasTab);
            app.RESULTADOVERDADEROLabel.Text = 'RESULTADO VERDADERO';
            app.resultadoVerdaderoDerivadaField = uieditfield(app.DerivadasTab, 'numeric');
            app.resultadoVerdaderoDerivadaField.Editable = 'off';
            app.IntegralesTab = uitab(app.mainTabGroup);
            app.IntegralesTab.Title = 'Integrales';
            app.IngreselafuncinaintegrarLabel = uilabel(app.IntegralesTab);
            app.IngreselafuncinaintegrarLabel.Text = '1. Ingrese la función a integrar';
            app.SigaelformatodeMatlabEditFieldLabel_4 = uilabel(app.IntegralesTab);
            app.SigaelformatodeMatlabEditFieldLabel_4.Text = 'Siga el formato de Matlab';
            app.funcionIntegralEditField = uieditfield(app.IntegralesTab, 'text');
            app.IngreseloslmitesdeintegracinLabel = uilabel(app.IntegralesTab);
            app.IngreseloslmitesdeintegracinLabel.Text = '2. Ingrese los límites de integración';
            app.aEditFieldLabel = uilabel(app.IntegralesTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.IntegralesTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.IntegralesTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.IntegralesTab, 'numeric');
            app.IngresencantidaddeparticionesLabel = uilabel(app.IntegralesTab);
            app.IngresencantidaddeparticionesLabel.Text = '3. Ingrese n (cantidad de particiones)';
            app.nEditFieldLabel = uilabel(app.IntegralesTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.IntegralesTab, 'numeric');
            app.SeleccioneelmtodoLabel_3 = uilabel(app.IntegralesTab);
            app.SeleccioneelmtodoLabel_3.Text = '4. Seleccione el método';
            app.metodoIntegralDrop = uidropdown(app.IntegralesTab);
            app.metodoIntegralDrop.Items = {'Trapecio', 'Simpson 1/3', 'Simpson 3/8'};
            app.metodoIntegralDrop.Value = 'Trapecio';
            app.resultadoAproximadoIntegralField = uieditfield(app.IntegralesTab, 'numeric');
            app.resultadoAproximadoIntegralField.Editable = 'off';
            app.RESULTADOAPROXIMADOLabel_2 = uilabel(app.IntegralesTab);
            app.RESULTADOAPROXIMADOLabel_2.Text = 'RESULTADO APROXIMADO';
            app.ERRORREALLabel_2 = uilabel(app.IntegralesTab);
            app.ERRORREALLabel_2.Text = 'ERROR REAL (%)';
            app.errorIntegralField = uieditfield(app.IntegralesTab, 'numeric');
            app.errorIntegralField.Editable = 'off';
            app.RESULTADOVERDADEROLabel_2 = uilabel(app.IntegralesTab);
            app.RESULTADOVERDADEROLabel_2.Text = 'RESULTADO VERDADERO';
            app.resultadoVerdaderoIntegralField = uieditfield(app.IntegralesTab, 'numeric');
            app.resultadoVerdaderoIntegralField.Editable = 'off';
            app.CalcularIntegralButton = uibutton(app.IntegralesTab, 'push');
            app.CalcularIntegralButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIntegralButtonPushed, true);
            app.CalcularIntegralButton.Text = 'Calcular';
            app.EcuacionesDiferencialesTab = uitab(app.mainTabGroup);
            app.EcuacionesDiferencialesTab.Title = 'Ecuaciones Diferenciales';
            app.rungeKutta3UI = uiaxes(app.EcuacionesDiferencialesTab);
            title(app.rungeKutta3UI, 'Runge-Kutta 3')
            xlabel(app.rungeKutta3UI, 'X')
            ylabel(app.rungeKutta3UI, 'Y')
            zlabel(app.rungeKutta3UI, 'Z')
            app.InfoRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.InfoRungeKutta3Label.Text = 'INGRESE LA SIGUIENTE INFORMACIÓN PARA RESOLVER POR RUNGE KUTTA 3';
            app.hRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.hRungeKutta3Label.Text = 'h:';
            app.yRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.yRungeKutta3Label.Text = 'y:';
            app.puntoParadaRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.puntoParadaRungeKutta3Label.Text = 'Punto de parada:';
            app.resultadoYRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.resultadoYRungeKutta3Label.Text = 'Yi+1 =';
            app.k1RungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.k1RungeKutta3Label.Text = 'K1 =';
            app.k2RungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.k2RungeKutta3Label.Text = 'K2 =';
            app.k3RungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.k3RungeKutta3Label.Text = 'K3 =';
            app.ecuacionDifRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.ecuacionDifRungeKutta3Label.Text = 'dy/dx =';
            app.xRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.xRungeKutta3Label.Text = 'x:';
            app.resultadoYRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.k1RungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.k3RungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.k2RungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.hRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.xRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.yRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.puntoParadaRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.CalcularRungeKutta3Button = uibutton(app.EcuacionesDiferencialesTab, 'push');
            app.CalcularRungeKutta3Button.ButtonPushedFcn = createCallbackFcn(app, @CalcularRungeKutta3ButtonPushed, true);
            app.CalcularRungeKutta3Button.Text = 'Calcular';
            app.RESULTADODELPROCESOLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.RESULTADODELPROCESOLabel.Text = 'RESULTADO DEL PROCESO';
            app.ecuacionDifRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.iteracionesRungeKutta3Label = uilabel(app.EcuacionesDiferencialesTab);
            app.iteracionesRungeKutta3Label.Text = 'Iteraciones =';
            app.iteracionesRungeKutta3EditField = uieditfield(app.EcuacionesDiferencialesTab, 'numeric');
            app.GRFICALabel = uilabel(app.EcuacionesDiferencialesTab);
            app.GRFICALabel.Text = 'GRÁFICA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyectoFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end